classdef rplidar2dtestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Button2   matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        oldpath = addpath('Project','C:\Users\wnghd\Desktop\Project');
        oldpath2 = addpath(genpath('hardwarex_with_3rd_support'));
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            hardwarex_init;
            pRPLIDAR = CreateRPLIDAR();
            [result] = ConnectRPLIDAR(pRPLIDAR, 'RPLIDAR0.txt');
            count = 0; alldistances = []; allangles = [];
            key = 0;
            while 1 % Wait for ESC key (ASCII code 27).
                [result, distances, angles, bNewScan, quality] = GetScanDataResponseRPLIDAR(pRPLIDAR);
                alldistances = [alldistances distances]; allangles = [allangles angles]; 
                count = count+1;
                %if bNewScan
                if count > 360
%                    clf; hold on; %axis([-scale,scale,-scale,scale,-scale,scale]);
%                    cloudpoints=transpose([alldistances.*cos(allangles); alldistances.*sin(allangles);zeros(1,361)]);
%                    xlim([-6 6]); ylim([-6 6]); zlim([-6 6]);
                   clf; hold on; 
%                    axis([-scale,scale,-scale,scale,-scale,scale]);
                   plot(app.UIAxes,alldistances.*cos(allangles), alldistances.*sin(allangles), '.');
                   pause(0.01); 
%                    key = get(gcf,'CurrentCharacter');
                   count = 0; alldistances = []; allangles = []; 
                end    
    
             end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
%             close(fig);

            [result] = DisconnectRPLIDAR(pRPLIDAR)
            DestroyRPLIDAR(pRPLIDAR);
            clear pRPLIDAR; % unloadlibrary might fail if all the variables that use types from the library are not removed...
            unloadlibrary('hardwarex');
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rplidar2dtestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end